classdef wipApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        BandpassTab              matlab.ui.container.Tab
        GridLayout2_2            matlab.ui.container.GridLayout
        EditField_7              matlab.ui.control.NumericEditField
        EditField_5              matlab.ui.control.NumericEditField
        EditField_4              matlab.ui.control.NumericEditField
        OrderLabel               matlab.ui.control.Label
        UpperboundLabel_2        matlab.ui.control.Label
        LowerboundLabel_2        matlab.ui.control.Label
        RMSwindowTab             matlab.ui.container.Tab
        GridLayout3_5            matlab.ui.container.GridLayout
        EditField_6              matlab.ui.control.NumericEditField
        WindowsizeLabel_2        matlab.ui.control.Label
        ExportPanel_2            matlab.ui.container.Panel
        GridLayout3_4            matlab.ui.container.GridLayout
        ExportsettingsButton_2   matlab.ui.control.Button
        ExportdataButton_2       matlab.ui.control.Button
        OutlierremovalPanel      matlab.ui.container.Panel
        GridLayout3_2            matlab.ui.container.GridLayout
        DropDown_2               matlab.ui.control.DropDown
        StandarddeviationsLabel  matlab.ui.control.Label
        FileinputPanel           matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        selectfileButton         matlab.ui.control.Button
        MCVfileLabel             matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        processing_track = "bandpass selected";
        filepath = "";
    end
    
    methods (Access = private)
        
        function ProcessEverything(app)
            if app.filepath ~= ""
                % all parameters
%                 lowerbound = app.EditField_4.Value;
%                 upperbound = app.EditField_5.Value;
%                 rmswindowsize = app.EditField_6.Value;
%                 std = app.DropDown_2.Value;
%                 lowerboundsteepness = app.Slider_3.Value;
%                 upperboundsteepness = app.Slider_4.Value;
%                 response = app.DropDown_3.Value;

                % start processing
                importdata(app);
            end
        end
        
        function importdata(app)
            disp("Importing data");
            header = jsondecode(strrep(string(textscan(fopen(path, "r"),'%s',1,'delimiter','\n', 'headerlines',1)),"# ", ""));
            raw_data = readmatrix(path, 'HeaderLines', 3, 'ExpectedNumVariables', 7);
            
            number_of_channels = 5;
            
            % Preallocation of resources
            
            data = zeros(length(raw_data), number_of_channels);       % 5 channels
            time = zeros(length(raw_data), 1);       % 1 time axis
            time = raw_data(:,1)./1000;
            
            MVConvert(app);
        end

        function MVConvert(app)
            disp("Converting to mV");
            L = length(data);
            % Sensor transfer function: EMG = ((((ADC/(2^n)) - 0.5)*VCC)/Gain) * 1000;        
            gain = 1000;        % Gain - 1000
            vcc = 3;            % VCC - 3V
            
            for row = 1:L
                for channel = 1:number_of_channels
                    % ADC - Value sampled from the channel
                    % n - Number of bits of the channel
                    n = header.x00_07_80_3B_46_63.resolution(channel);
                    data(row, channel) = ((((raw_data(row, channel + 2) / (2^n)) - 0.5) * vcc) / gain) * 1000;
                end
            end

            FFTTransform(app);
        end

        
        function FFTTransform(app)
            disp("Applying FFT");
            fs = header.x00_07_80_3B_46_63.samplingRate;        % Sampling frequency
            f = fs * (0:(L/2)) / L;                             % Frequency range (axis)
            
            for channel = 1:number_of_channels
                % Two sided spectrum (-Fmax:Fmax)
                p1 = fft(data(:, channel));
            
                % Normalisation of the power of the output ( divistion by L)
                p1 = abs(p1/L);
            
                % Single sided spectrum (positive part of two sided spectrum * 2)
                p1 = p1(1:L/2+1);
                p1(2:end-1) = 2 * p1(2:end-1);
            
                % Plot the fft data
                subplot(number_of_channels, 1, channel);            
            end
            OutLierRemovalTrack(app);
        end

        function OutLierRemovalTrack(app)
            disp("Removing outliers");
            std = app.DropDown_2.Value;
            for channel = 1:number_of_channels
                data(:,channel) = filloutliers(data(:,channel),'linear','mean','ThresholdFactor', std);
            end
           
            ABTrack(app);
        end

        % select processingtrack
        function ABTrack(app)
            disp("Selecting Processing Track");
            RectifySignal(app);
            if app.processing_track == "bandpass selected"
                bandpassTrack(app);
            else
                rmsTrack(app);
            end
        end

        function RectifySignal(app)
            % Preallocate resources
            rec_signal = zeros(length(data), 3);
            
            for channel = 1:number_of_channels
                rec_signal(:,channel) = abs(data(:,channel));
            end
        end
        
        function BandpassTrack(app)
            disp("Applying bandpass filter on signal");
            % Filter options
            order = 4;
            fnyq = fs/2;
            fcutlow = app.EditField_4.Value;
            fcuthigh = app.EditField_5.Value;
            
            
            % Butterworth bandpass filter
            [b,a] = butter(order, [fcutlow, fcuthigh] / fnyq, "bandpass");
            
            for channel = 1:number_of_channels
                data(:,channel) = filtfilt(b, a, data(:,channel));       % Zero-phase digital filtering
            end
            MVCNormaliseTrack(app)
        end

        
        function RMSTrack(app)
            disp("Applying RMS window on signal");
            envelope = zeros(L, number_of_channels);
            % Windo size in ms
            window = 50;
            
            for channel = 1:number_of_channels
                envelope(:, channel) = sqrt(movmean((rec_signal(:, channel).^2), window));      % RMS
            end
            MVCNormaliseTrack(app)
        end

        function MVCNormaliseTrack(app)
            disp("Normalising the MVC data");
            % Preallocate resources
            % Maximum Voluntary Contraction (MVC)
            MVC = [0 0 0 0 0];
            MVC_normalised = zeros(L, number_of_channels);
            
            % Find the MVC value for each channel (max value in raw data)
            for channel = 1:number_of_channels
                MVC(:,channel) = max(data(:, channel));
            end
            
            % Divide signal by MVC and convert to percentage
            for channel = 1:number_of_channels
                MVC_normalised(:,channel) = (envelope(:, channel) ./ MVC(1,channel)) .* 100;
            end
            % enable export
            app.ExportPanel_2.Enable;
        end

        function ExportData(app)
            
        end

        function ExportSettings(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectfileButton
        function FileSelect(app, event)

            try
                [file,path] = uigetfile('*.txt')  %open a file
                app.filepath = string(path)+string(file);
            catch error
                disp(error)
            end

            % after file was succesfully selected enable all the parameters
            lowerbound = app.EditField_4.Enable;
            upperbound = app.EditField_5.Enable;
            rmswindowsize = app.EditField_6.Enable;
            std = app.DropDown_2.Value;
            lowerboundsteepness = app.Slider_3.Enable;
            upperboundsteepness = app.Slider_4.Enable;
            response = app.DropDown_3.Enable;
            
            ProcessEverything(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            BandpassTrack(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            BandpassTrack(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            OutLierRemovalTrack(app);
        end

        % Callback function
        function Slider_3ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Callback function
        function Slider_4ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Callback function
        function DropDown_3ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if exist(path, 'file')
                if app.TabGroup.SelectedTab == app.BandpassTab
                    app.processing_track = "bandpass selected";
                    BandpassTrack(app);
                else
                    app.processing_track = "rms selected";
                    RMSTrack(app);
                end
            end
            ProcessEverything(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileinputPanel = uipanel(app.LeftPanel);
            app.FileinputPanel.Title = 'File input';
            app.GridLayout3 = uigridlayout(app.FileinputPanel);
            app.MCVfileLabel = uilabel(app.GridLayout3);
            app.MCVfileLabel.Text = 'MCV file:';
            app.selectfileButton = uibutton(app.GridLayout3, 'push');
            app.selectfileButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelect, true);
            app.selectfileButton.Text = 'select file';
            app.OutlierremovalPanel = uipanel(app.LeftPanel);
            app.OutlierremovalPanel.Title = 'Outlier removal';
            app.GridLayout3_2 = uigridlayout(app.OutlierremovalPanel);
            app.StandarddeviationsLabel = uilabel(app.GridLayout3_2);
            app.StandarddeviationsLabel.Text = 'Standard deviations';
            app.DropDown_2 = uidropdown(app.GridLayout3_2);
            app.DropDown_2.Items = {'1', '2', '3'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Tag = 'std';
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = '1';
            app.ExportPanel_2 = uipanel(app.LeftPanel);
            app.ExportPanel_2.Enable = 'off';
            app.ExportPanel_2.Title = 'Export';
            app.GridLayout3_4 = uigridlayout(app.ExportPanel_2);
            app.ExportdataButton_2 = uibutton(app.GridLayout3_4, 'push');
            app.ExportdataButton_2.Text = 'Export data';
            app.ExportsettingsButton_2 = uibutton(app.GridLayout3_4, 'push');
            app.ExportsettingsButton_2.Text = 'Export settings';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.BandpassTab = uitab(app.TabGroup);
            app.BandpassTab.Title = 'Bandpass';
            app.GridLayout2_2 = uigridlayout(app.BandpassTab);
            app.LowerboundLabel_2 = uilabel(app.GridLayout2_2);
            app.LowerboundLabel_2.Text = 'Lowerbound';
            app.UpperboundLabel_2 = uilabel(app.GridLayout2_2);
            app.UpperboundLabel_2.Text = 'Upperbound';
            app.OrderLabel = uilabel(app.GridLayout2_2);
            app.OrderLabel.Text = 'Order';
            app.EditField_4 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Tag = 'lowerbound';
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Tag = 'upperbound';
            app.EditField_5.Enable = 'off';
            app.EditField_7 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.Enable = 'off';
            app.RMSwindowTab = uitab(app.TabGroup);
            app.RMSwindowTab.Title = 'RMS window';
            app.GridLayout3_5 = uigridlayout(app.RMSwindowTab);
            app.WindowsizeLabel_2 = uilabel(app.GridLayout3_5);
            app.WindowsizeLabel_2.Text = 'Window size';
            app.EditField_6 = uieditfield(app.GridLayout3_5, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Tag = 'rmswindow';
            app.EditField_6.Enable = 'off';
            app.EditField_6.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wipApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end